classdef Group13 < matlab.apps.AppBase

    % Properties that correspond to app components
    properties (Access = public)
        UIFigure                  matlab.ui.Figure
        GROUP13WELCOMETOIMAGEPROCESSINGAPPLabel  matlab.ui.control.Label
        PRESSTOSTARTButton        matlab.ui.control.Button
        MainMenuPanel             matlab.ui.container.Panel
        Panel8                    matlab.ui.container.Panel
        BackButton_7              matlab.ui.control.Button
        parameter_a               matlab.ui.control.NumericEditField
        MeanLabel_5               matlab.ui.control.Label
        parameter_b               matlab.ui.control.NumericEditField
        StandardDeviationLabel_5  matlab.ui.control.Label
        Panel7                    matlab.ui.container.Panel
        BackButton_6              matlab.ui.control.Button
        pepper                    matlab.ui.control.NumericEditField
        MeanLabel_4               matlab.ui.control.Label
        salt                      matlab.ui.control.NumericEditField
        StandardDeviationLabel_4  matlab.ui.control.Label
        Panel6                    matlab.ui.container.Panel
        BackButton_5              matlab.ui.control.Button
        SigmaValue                matlab.ui.control.NumericEditField
        StandardDeviationLabel    matlab.ui.control.Label
        MuValue                   matlab.ui.control.NumericEditField
        MeanLabel                 matlab.ui.control.Label
        Panel5                    matlab.ui.container.Panel
        MaximumWindowSize         matlab.ui.control.NumericEditField
        MaximumWindowSizeEditFieldLabel  matlab.ui.control.Label
        BackButton_4              matlab.ui.control.Button
        Panel4                    matlab.ui.container.Panel
        BackButton_3              matlab.ui.control.Button
        StandardDerivation        matlab.ui.control.NumericEditField
        MaximumwindowsizeLabel    matlab.ui.control.Label
        Panel3                    matlab.ui.container.Panel
        BackButton_2              matlab.ui.control.Button
        Parameter                 matlab.ui.control.NumericEditField
        EntertheparameterLabel    matlab.ui.control.Label
        Panel2                    matlab.ui.container.Panel
        BackButton                matlab.ui.control.Button
        KernelSize                matlab.ui.control.NumericEditField
        EnterthekernelsizeLabel   matlab.ui.control.Label
        Exit                      matlab.ui.control.Image
        Reset                     matlab.ui.control.Image
        SaveButton                matlab.ui.control.Button
        OperationsListBox         matlab.ui.control.ListBox
        OperationsListBoxLabel    matlab.ui.control.Label
        AddimageButton            matlab.ui.control.Button
        UIAxes_2                  matlab.ui.control.UIAxes
        UIAxes                    matlab.ui.control.UIAxes
    end

    
    properties (Access = public)
        selectedImage;
        processedImage;
    end


    % Callbacks that handle component events
    methods (Access = private)

        % Button pushed function: AddimageButton
        function ButtonPushed(app, event)
            [file, path] = uigetfile({'*.jpg;*.png;*.bmp;*.pgm', 'Image Files (*.jpg, *.png, *.bmp, *.pgm)'; '*.*', 'All Files (*.*)'}, 'Bir görüntü seçin');
            
            if isequal(file, 0)
                return;
            else
                fullFilePath = fullfile(path, file);
                imshow(fullFilePath, 'Parent', app.UIAxes);
                app.selectedImage = imread(fullFilePath);
            end
        end

        % Double-clicked callback: OperationsListBox
        function ListBoxOperations(app, event)
            % Get the selected item indices from the listbox
            selectedOperation = app.OperationsListBox.Value;
            img=app.selectedImage;

            switch selectedOperation
                case 'Histogram Equalization'
                    %Computing the histogram
                    [freq, bins] = imhist(img);
                    app.processedImage=histfilt(freq,bins,img);
                    imshow(app.processedImage, 'Parent', app.UIAxes_2);

                case 'Mean (Average) Filtering'
                    app.Panel2.Visible="on";

                case 'Median Filtering'
                    app.Panel2.Visible="on";

                case 'Adaptive Median Filtering'
                    app.Panel5.Visible="on";

                case 'Gaussian Filtering (Smoothing)'
                    app.Panel4.Visible="on";

                case 'Additive Uniform Noise'
                    app.Panel8.Visible="on";

                case 'Additive Gaussian Noise'
                    app.Panel6.Visible="on";

                case 'Additive Salt&Pepper Noise'
                    app.Panel7.Visible="on";

                case 'Additive LogNormal Noise'
                    app.Panel8.Visible="on";

                case 'Additive Rayleigh Noise'
                    app.Panel8.Visible="on";

                case 'Additive Exponential Noise'
                    app.Panel3.Visible="on";

                case 'Additive Erlang Noise'
                    app.Panel8.Visible="on";

            end
                
        end

        % Value changed function: KernelSize
        function KernelSizeValueChanged(app, event)
            app.Panel2.Visible="off";
            selectedOperation = app.OperationsListBox.Value;
            img=app.selectedImage;

            switch selectedOperation
                case 'Mean (Average) Filtering'
                    kernel_size=app.KernelSize.Value;
                    img=double(img);
                    % Check if the entered kernel size is odd
                    if mod(kernel_size, 2) == 0
                        msgbox('Kernel size should be odd', 'Invalid Kernel Size', 'error');
                        app.KernelSize.Value=[];
                        app.Panel2.Visible="on";
                        return;
                    end
                    
                    % Check if the entered kernel size is negative
                    if kernel_size<0
                        msgbox('Kernel size cannot be minus', 'Invalid Kernel Size', 'error');
                        app.KernelSize.Value=[];
                        app.Panel2.Visible="on";
                        return;
                    end
                    
                    % Calling the function
                    Z=meanfilt(kernel_size);
                    % Applying the kernel
                    app.processedImage = imfilter(img, Z);
                    app.processedImage=uint8(app.processedImage);
                    imshow(app.processedImage, 'Parent', app.UIAxes_2);

                case 'Median Filtering'
                    kernel_size=app.KernelSize.Value;
                    img=double(img);
                    % Check if the entered kernel size is odd
                    if mod(kernel_size, 2) == 0
                        msgbox('Kernel size should be odd', 'Invalid Kernel Size', 'error');
                        app.KernelSize.Value=[];
                        app.Panel2.Visible="on";
                        return;
                    end
                    
                    % Check if the entered kernel size is negative
                    if kernel_size<0
                        msgbox('Kernel size cannot be minus', 'Invalid Kernel Size', 'error');
                        app.KernelSize.Value=[];
                        app.Panel2.Visible="on";
                        return;
                    end
            
                    [rows, cols] = size(img);
                    % Calling the function
                    app.processedImage=medianfilt(img,kernel_size,rows,cols);
                    app.processedImage=uint8(app.processedImage);
                    
                    imshow(app.processedImage, 'Parent', app.UIAxes_2);
                    
            end
            % Close the panel and clear the edit field
            app.Panel2.Visible="off";
            app.KernelSize.Value=[];
        end

        % Button pushed function: PRESSTOSTARTButton
        function PRESSTOSTARTButtonPushed(app, event)
            app.PRESSTOSTARTButton.Visible="off";
            app.GROUP13WELCOMETOIMAGEPROCESSINGAPPLabel.Visible="off";
            app.MainMenuPanel.Visible="on";       
        end

        % Button pushed function: SaveButton
        function saveButton(app, event)
            if isempty(app.processedImage) || isempty(app.selectedImage)
                msgbox('You cannot save an empty file!', 'Empty file!', 'error');
            else
                % Prompt the user to select a location to save the image
                [file, path] = uiputfile({'*.png', 'PNG Files (*.png)'; '*.jpg', 'JPEG Files (*.jpg)'; '*.bmp', 'BMP Files (*.bmp)'; '*.pgm', 'PGM Files (*.pgm)'}, 'Save Image As');
                if isequal(file, 0) || isequal(path, 0)
                    % User canceled, return
                    return;
                end
                % Save the image
                imwrite(app.processedImage, fullfile(path, file));
                msgbox('File saved successfully', 'Success');
            end
        end

        % Value changed function: Parameter
        function ParameterValueChanged(app, event)
            app.Panel3.Visible="off";
            selectedOperation = app.OperationsListBox.Value;
            img=app.selectedImage;

            switch selectedOperation
                 case 'Additive Exponential Noise'
                     parameter = app.Parameter.Value;
                     parameter_zero=0;

                     if parameter <= 0
                        msgbox('Parameter must be positive for exponential distribution', 'Invalid Parameter', 'error');
                        app.Parameter.Value=[];
                        app.Panel3.Visible="on";
                        return;
                     end
                    
                    % Convert the image to double format
                    img = im2double(img);
                    [rows, cols] = size(img);
                    
                    % Calling the function
                    Z=imnoise2('exponential',rows,cols,parameter,parameter_zero);
                    
                    % Apply noise onto the image 
                    app.processedImage=img+Z;
                    app.processedImage = mat2gray(app.processedImage);
                    app.processedImage=uint8(app.processedImage*255);
                    imshow(app.processedImage, 'Parent', app.UIAxes_2);
                
            end
            % Close the panel and clear the edit field
            app.Panel3.Visible="off";
            app.Parameter.Value=[];
            
        end

        % Button pushed function: BackButton_3
        function BackButton_3Pushed(app, event)
            app.StandardDerivation.Value=[];
            app.Panel4.Visible="off";
        end

        % Button pushed function: BackButton_2
        function BackButton_2Pushed(app, event)
            app.Parameter.Value=[];
            app.Panel3.Visible="off";
        end

        % Button pushed function: BackButton
        function BackButtonPushed(app, event)
            app.KernelSize.Value=[];
            app.Panel2.Visible="off";
        end

        % Value changed function: StandardDerivation
        function StandardDerivationValueChanged(app, event)
            app.Panel4.Visible="off";
            selectedOperation = app.OperationsListBox.Value;
            img=app.selectedImage;

            switch selectedOperation
                case 'Gaussian Filtering (Smoothing)'
                    % Convert the standard deviation to sigma for the Gaussian function
                    sigma = double(app.StandardDerivation.Value);
                    img=double(img);
        
                    % Define the size of the filter kernel
                    filter_size = 2 * ceil(3 * sigma) + 1;
    
                    % Ensure that the filter size is odd
                    filter_size = round(filter_size);
                    if mod(filter_size, 2) == 0
                        msgbox('Filter size must be odd', 'Invalid filter size', 'error');
                        app.StandardDerivation.Value=[];
                        app.Panel4.Visible="on";
                        return;
                    end

                    if filter_size<0
                        msgbox('Filter size cannot be negative', 'Invalid filter size', 'error');
                        app.StandardDerivation.Value=[];
                        app.Panel4.Visible="on";
                        return;
                    end
                    
                    % Calling the function
                    app.processedImage=gaussianfilt(img,sigma,filter_size);
                    app.processedImage=uint8(app.processedImage);
                    imshow(app.processedImage, 'Parent', app.UIAxes_2);

            end

            % close the panel and clear the edit field
            app.Panel4.Visible="off";
            app.StandardDerivation.Value=[];
            
        end

        % Button pushed function: BackButton_4
        function BackButton_4Pushed(app, event)
            app.MaximumWindowSize.Value=[];
            app.Panel5.Visible="off";
        end

        % Value changed function: MaximumWindowSize
        function MaximumWindowSizeValueChanged(app, event)
            app.Panel5.Visible="off";
            selectedOperation = app.OperationsListBox.Value;
            img=app.selectedImage;

            switch selectedOperation
                case 'Adaptive Median Filtering'
                    % user enters the maximum window size
                    inputWindowSize = app.MaximumWindowSize.Value;
                    img=double(img);

                    % window size must be odd
                    if mod(inputWindowSize, 2) == 0
                        msgbox('Window size should be odd', 'Invalid Window Size', 'error');
                        app.MaximumWindowSize.Value=[];
                        app.Panel5.Visible="on";
                        return;
                    end
        
                    [rows, cols] = size(img);
                    
                    % Calling the function
                    app.processedImage=adaptivefilt(img,rows,cols,inputWindowSize);
                    app.processedImage=uint8(app.processedImage);

                    % Display the result
                    imshow(app.processedImage, 'Parent', app.UIAxes_2);
                    
            end

            % Close the panel and clear the edit field
            app.Panel5.Visible="off";
            app.MaximumWindowSize.Value=[];
            
        end

        % Button pushed function: BackButton_5
        function BackButton_5Pushed(app, event)
            app.SigmaValue.Value=[];
            app.MuValue.Value=[];
            app.Panel6.Visible="off";
        end

        % Value changed function: SigmaValue
        function SigmaValueValueChanged(app, event)
            app.Panel6.Visible="off";
            selectedOperation = app.OperationsListBox.Value;
            img=app.selectedImage;
            switch selectedOperation

                case 'Additive Gaussian Noise'
                    % Get user parameters
                    meanValue = double(app.MuValue.Value);
                    stdDeviation = double(app.SigmaValue.Value);
                    
                    % the image should be in double format
                    img = im2double(img);
                    [rows, cols] = size(img);
                    
                    % Calling the function
                    Z=imnoise2('gaussian',rows,cols,meanValue,stdDeviation);
                    
                    %Apply the noise onto the image
                    app.processedImage=img+Z;
                    app.processedImage = mat2gray(app.processedImage);
                    app.processedImage=uint8(app.processedImage*255);
                    imshow(app.processedImage, 'Parent', app.UIAxes_2);
            end
            
            % Close the panel and clear the edit field
            app.SigmaValue.Value=[];
            app.MuValue.Value=[];
        end

        % Image clicked function: Exit
        function ExitClicked(app, event)
            delete(app);
        end

        % Image clicked function: Reset
        function ResetImageClicked(app, event)
            % Clear the input and output images
            app.selectedImage=[];
            app.processedImage=[];
            cla(app.UIAxes); % Clear the output axes
            cla(app.UIAxes_2); % Clear the input axes
        end

        % Button pushed function: BackButton_6
        function BackButton_6Pushed(app, event)
            app.pepper.Value=[];
            app.salt.Value=[];
            app.Panel7.Visible="off";
        end

        % Value changed function: salt
        function saltValueChanged(app, event)
            app.Panel7.Visible="off";
            selectedOperation = app.OperationsListBox.Value;
            img=app.selectedImage;

            switch selectedOperation
                 case 'Additive Salt&Pepper Noise'
                    salt_parameter = app.pepper.Value;
                    pepper_parameter = app.salt.Value;
                
                    if salt_parameter < 0 || salt_parameter > 1 || pepper_parameter < 0 || pepper_parameter > 1 || pepper_parameter+salt_parameter>1
                        msgbox('You should input values between 0 and 1 for both salt and pepper and total must be less or equal than 1!', 'Invalid input', 'error');
                        app.pepper.Value = [];
                        app.salt.Value = [];
                        app.Panel7.Visible = "on";
                        return;
                    else
                    
                    % Ensure that the image is in double format
                    img = im2double(img);
                    [rows, cols] = size(img);
                    
                    % Calling the function
                    Z=imnoise2('salt & pepper',rows,cols,salt_parameter,pepper_parameter);
                    
                    %Apply the noise onto the image
                    app.processedImage=img+Z;
                    app.processedImage = mat2gray(app.processedImage);
                    app.processedImage=uint8(app.processedImage*255);
                    imshow(app.processedImage, 'Parent', app.UIAxes_2);
                    end
            end
            % Clear the edit field
            app.pepper.Value = [];
            app.salt.Value = [];
            
        end

        % Button pushed function: BackButton_7
        function BackButton_7Pushed(app, event)
            app.parameter_a.Value=[];
            app.parameter_b.Value=[];
            app.Panel8.Visible="off";
        end

        % Value changed function: parameter_b
        function parameter_bValueChanged(app, event)
            app.Panel8.Visible="off";
            selectedOperation = app.OperationsListBox.Value;
            img=app.selectedImage;
            switch selectedOperation
                case 'Additive LogNormal Noise'
                    parameter1 = double(app.parameter_a.Value);
                    parameter2 = double(app.parameter_b.Value);

                    % Ensure that the image is in double format
                    img = im2double(img);
                    [rows, cols] = size(img);
                    
                    % Calling the function
                    Z=imnoise2('lognormal',rows,cols,parameter1,parameter2);
                    
                    %Apply the noise onto the image
                    app.processedImage=img.*Z;
                    app.processedImage = mat2gray(app.processedImage);
                    app.processedImage=uint8(app.processedImage*255);
                    imshow(app.processedImage, 'Parent', app.UIAxes_2);

                case 'Additive Rayleigh Noise'
                    parameter1 = double(app.parameter_a.Value);
                    parameter2 = double(app.parameter_b.Value);

                    % Ensure that the image is in double format
                    img = im2double(img);
                    [rows, cols] = size(img);
                    
                    %Call the function
                    Z=imnoise2('rayleigh',rows,cols,parameter1,parameter2);
                    
                    %Apply the noise onto the image
                    app.processedImage=img+Z;
                    app.processedImage=uint8(app.processedImage*255);
                    imshow(app.processedImage, 'Parent', app.UIAxes_2);

                case 'Additive Erlang Noise'
                    a = app.parameter_a.Value; % Use MinGray for min gray level 'a'
                    b = app.parameter_b.Value; % Use MinGray for min gray level 'b'
                    
                    if a <= 0 || b <= 0
                        msgbox('Both a and b must be positive integer, and b must be smaller than a.', 'Invalid input', 'error');
                        app.parameter_a.Value = [];
                        app.parameter_b.Value = [];
                        app.Panel8.Visible = "on";
                        return;
                    end

                    % the image should be in double format
                    img = im2double(img);
                    [rows, cols] = size(img);
                    
                    %Call the function
                    Z=imnoise2('erlang',rows,cols,a,b);
                    
                    %Apply the noise onto the image
                    app.processedImage=img+Z;
                    app.processedImage=uint8(app.processedImage*255);
                    imshow(app.processedImage, 'Parent', app.UIAxes_2);

                case 'Additive Uniform Noise'
                    % Get user parameters
                    minValue = double(app.parameter_a.Value);
                    maxValue = double(app.parameter_b.Value);
        
                    % Ensure that the image is in double format
                    img = im2double(img);
                    [rows, cols] = size(img);
                    
                    %Call the function
                    Z=imnoise2('uniform',rows,cols,minValue,maxValue);
                    %Apply the noise onto the image
                    app.processedImage=img+Z;
                    app.processedImage = mat2gray(app.processedImage);
                    app.processedImage=uint8(app.processedImage*255);
                    imshow(app.processedImage, 'Parent', app.UIAxes_2);

            end 
            %Clear the edit field
            app.parameter_a.Value=[];
            app.parameter_b.Value=[];
        end
    end

    % Component initialization
    methods (Access = private)

        % Create UIFigure and components
        function createComponents(app)
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            % Get the file path for locating images
            pathToMLAPP = fileparts(mfilename('fullpath'));
            app.UIFigure = uifigure('Visible', 'off');
            app.UIFigure.Name = 'MATLAB App';
            app.MainMenuPanel = uipanel(app.UIFigure);
            app.MainMenuPanel.Title = 'Main Menu';
            app.MainMenuPanel.Visible = 'off';
            app.UIAxes = uiaxes(app.MainMenuPanel);
            title(app.UIAxes, 'Original Image')
            app.UIAxes.XTick = [];
            app.UIAxes.XTickLabel = '';
            app.UIAxes.YTick = [];
            app.UIAxes.YTickLabel = '';
            app.UIAxes_2 = uiaxes(app.MainMenuPanel);
            title(app.UIAxes_2, 'Processed Image')
            app.UIAxes_2.XTick = [];
            app.UIAxes_2.XTickLabel = '';
            app.UIAxes_2.YTick = [];
            app.UIAxes_2.ZTick = [];
            app.AddimageButton = uibutton(app.MainMenuPanel, 'push');
            app.AddimageButton.ButtonPushedFcn = createCallbackFcn(app, @ButtonPushed, true);
            app.AddimageButton.Text = 'Add image';
            app.OperationsListBoxLabel = uilabel(app.MainMenuPanel);
            app.OperationsListBoxLabel.Text = 'Operations';
            app.OperationsListBox = uilistbox(app.MainMenuPanel);
            app.OperationsListBox.Items = {'Histogram Equalization', 'Mean (Average) Filtering', 'Median Filtering', 'Adaptive Median Filtering', 'Gaussian Filtering (Smoothing)', 'Additive Uniform Noise', 'Additive Gaussian Noise', 'Additive Salt&Pepper Noise', 'Additive LogNormal Noise', 'Additive Rayleigh Noise', 'Additive Exponential Noise', 'Additive Erlang Noise'};
            app.OperationsListBox.DoubleClickedFcn = createCallbackFcn(app, @ListBoxOperations, true);
            app.OperationsListBox.Value = 'Histogram Equalization';
            app.SaveButton = uibutton(app.MainMenuPanel, 'push');
            app.SaveButton.ButtonPushedFcn = createCallbackFcn(app, @saveButton, true);
            app.SaveButton.Text = 'Save';
            app.Reset = uiimage(app.MainMenuPanel);
            app.Reset.ImageClickedFcn = createCallbackFcn(app, @ResetImageClicked, true);
            app.Reset.ImageSource = fullfile(pathToMLAPP, 'reset.png');
            app.Exit = uiimage(app.MainMenuPanel);
            app.Exit.ImageClickedFcn = createCallbackFcn(app, @ExitClicked, true);
            app.Exit.ImageSource = fullfile(pathToMLAPP, 'shutdown.png');
            app.Panel2 = uipanel(app.MainMenuPanel);
            app.Panel2.Title = 'Panel2';
            app.Panel2.Visible = 'off';
            app.EnterthekernelsizeLabel = uilabel(app.Panel2);
            app.EnterthekernelsizeLabel.Text = 'Enter the kernel size:';
            app.KernelSize = uieditfield(app.Panel2, 'numeric');
            app.KernelSize.AllowEmpty = 'on';
            app.KernelSize.ValueChangedFcn = createCallbackFcn(app, @KernelSizeValueChanged, true);
            app.KernelSize.Value = [];
            app.BackButton = uibutton(app.Panel2, 'push');
            app.BackButton.ButtonPushedFcn = createCallbackFcn(app, @BackButtonPushed, true);
            app.BackButton.Text = 'Back';
            app.Panel3 = uipanel(app.MainMenuPanel);
            app.Panel3.Title = 'Panel3';
            app.Panel3.Visible = 'off';
            app.EntertheparameterLabel = uilabel(app.Panel3);
            app.EntertheparameterLabel.Text = 'Enter the parameter:';
            app.Parameter = uieditfield(app.Panel3, 'numeric');
            app.Parameter.AllowEmpty = 'on';
            app.Parameter.ValueChangedFcn = createCallbackFcn(app, @ParameterValueChanged, true);
            app.Parameter.Value = [];
            app.BackButton_2 = uibutton(app.Panel3, 'push');
            app.BackButton_2.ButtonPushedFcn = createCallbackFcn(app, @BackButton_2Pushed, true);
            app.BackButton_2.Text = 'Back';
            app.Panel4 = uipanel(app.MainMenuPanel);
            app.Panel4.Title = 'Panel4';
            app.Panel4.Visible = 'off';
            app.MaximumwindowsizeLabel = uilabel(app.Panel4);
            app.MaximumwindowsizeLabel.Text = 'Standard derivation:';
            app.StandardDerivation = uieditfield(app.Panel4, 'numeric');
            app.StandardDerivation.AllowEmpty = 'on';
            app.StandardDerivation.ValueChangedFcn = createCallbackFcn(app, @StandardDerivationValueChanged, true);
            app.StandardDerivation.Value = [];
            app.BackButton_3 = uibutton(app.Panel4, 'push');
            app.BackButton_3.ButtonPushedFcn = createCallbackFcn(app, @BackButton_3Pushed, true);
            app.BackButton_3.Text = 'Back';
            app.Panel5 = uipanel(app.MainMenuPanel);
            app.Panel5.Title = 'Panel5';
            app.Panel5.Visible = 'off';
            app.BackButton_4 = uibutton(app.Panel5, 'push');
            app.BackButton_4.ButtonPushedFcn = createCallbackFcn(app, @BackButton_4Pushed, true);
            app.BackButton_4.Text = 'Back';
            app.MaximumWindowSizeEditFieldLabel = uilabel(app.Panel5);
            app.MaximumWindowSizeEditFieldLabel.Text = 'Maximum Window Size';
            app.MaximumWindowSize = uieditfield(app.Panel5, 'numeric');
            app.MaximumWindowSize.AllowEmpty = 'on';
            app.MaximumWindowSize.ValueChangedFcn = createCallbackFcn(app, @MaximumWindowSizeValueChanged, true);
            app.MaximumWindowSize.Value = [];
            app.Panel6 = uipanel(app.MainMenuPanel);
            app.Panel6.Title = 'Panel4';
            app.Panel6.Visible = 'off';
            app.MeanLabel = uilabel(app.Panel6);
            app.MeanLabel.Text = 'Mean:';
            app.MuValue = uieditfield(app.Panel6, 'numeric');
            app.MuValue.AllowEmpty = 'on';
            app.MuValue.Value = [];
            app.StandardDeviationLabel = uilabel(app.Panel6);
            app.StandardDeviationLabel.Text = 'Standard Deviation:';
            app.SigmaValue = uieditfield(app.Panel6, 'numeric');
            app.SigmaValue.AllowEmpty = 'on';
            app.SigmaValue.ValueChangedFcn = createCallbackFcn(app, @SigmaValueValueChanged, true);
            app.SigmaValue.Value = [];
            app.BackButton_5 = uibutton(app.Panel6, 'push');
            app.BackButton_5.ButtonPushedFcn = createCallbackFcn(app, @BackButton_5Pushed, true);
            app.BackButton_5.Text = 'Back';
            app.Panel7 = uipanel(app.MainMenuPanel);
            app.Panel7.Title = 'Panel7';
            app.Panel7.Visible = 'off';
            app.StandardDeviationLabel_4 = uilabel(app.Panel7);
            app.StandardDeviationLabel_4.Text = 'Salt:';
            app.salt = uieditfield(app.Panel7, 'numeric');
            app.salt.AllowEmpty = 'on';
            app.salt.ValueChangedFcn = createCallbackFcn(app, @saltValueChanged, true);
            app.salt.Value = [];
            app.MeanLabel_4 = uilabel(app.Panel7);
            app.MeanLabel_4.Text = 'Pepper:';
            app.pepper = uieditfield(app.Panel7, 'numeric');
            app.pepper.AllowEmpty = 'on';
            app.pepper.Value = [];
            app.BackButton_6 = uibutton(app.Panel7, 'push');
            app.BackButton_6.ButtonPushedFcn = createCallbackFcn(app, @BackButton_6Pushed, true);
            app.BackButton_6.Text = 'Back';
            app.Panel8 = uipanel(app.MainMenuPanel);
            app.Panel8.Title = 'Panel7';
            app.Panel8.Visible = 'off';
            app.StandardDeviationLabel_5 = uilabel(app.Panel8);
            app.StandardDeviationLabel_5.Text = 'Parameter B:';
            app.parameter_b = uieditfield(app.Panel8, 'numeric');
            app.parameter_b.AllowEmpty = 'on';
            app.parameter_b.ValueChangedFcn = createCallbackFcn(app, @parameter_bValueChanged, true);
            app.parameter_b.Value = [];
            app.MeanLabel_5 = uilabel(app.Panel8);
            app.MeanLabel_5.Text = 'Parameter A:';
            app.parameter_a = uieditfield(app.Panel8, 'numeric');
            app.parameter_a.AllowEmpty = 'on';
            app.parameter_a.Value = [];
            app.BackButton_7 = uibutton(app.Panel8, 'push');
            app.BackButton_7.ButtonPushedFcn = createCallbackFcn(app, @BackButton_7Pushed, true);
            app.BackButton_7.Text = 'Back';
            app.PRESSTOSTARTButton = uibutton(app.UIFigure, 'push');
            app.PRESSTOSTARTButton.ButtonPushedFcn = createCallbackFcn(app, @PRESSTOSTARTButtonPushed, true);
            app.PRESSTOSTARTButton.Text = 'PRESS TO START';
            app.GROUP13WELCOMETOIMAGEPROCESSINGAPPLabel = uilabel(app.UIFigure);
            app.GROUP13WELCOMETOIMAGEPROCESSINGAPPLabel.Text = {'GROUP13'; ''; 'WELCOME TO '; '          IMAGE PROCESSING '; '                                        APP'};
            % Show the figure after all components are created
            app.UIFigure.Visible = 'on';
        end
    end

    % App creation and deletion
    methods (Access = public)

        % Construct app
        function app = Group13

            % Create UIFigure and components
            createComponents(app)

            % Register the app with App Designer
            registerApp(app, app.UIFigure)

            if nargout == 0
                clear app
            end
        end

        % Code that executes before app deletion
        function delete(app)

            % Delete UIFigure when app is deleted
            delete(app.UIFigure)
        end
    end
end